classdef hammer_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LatestacquisitionTab            matlab.ui.container.Tab
        ImpactWaveformAxes              matlab.ui.control.UIAxes
        ImpactPSDAxes                   matlab.ui.control.UIAxes
        PSDAxes                         matlab.ui.control.UIAxes
        CoheranceAxes                   matlab.ui.control.UIAxes
        FrequencyResponseAxes           matlab.ui.control.UIAxes
        ChannelSettingsPanel            matlab.ui.container.Panel
        ChannelnameEditFieldLabel       matlab.ui.control.Label
        ChannelnameEditField            matlab.ui.control.EditField
        DevicetouseDropDown             matlab.ui.control.DropDown
        MeasurementTypeDropDown         matlab.ui.control.DropDown
        ACcouplingCheckBox              matlab.ui.control.CheckBox
        ChannelIDDropDown               matlab.ui.control.DropDown
        ExcitationcurrentAEditFieldLabel  matlab.ui.control.Label
        ExcitationcurrentAEditField     matlab.ui.control.NumericEditField
        InternalSensitivityEditFieldLabel  matlab.ui.control.Label
        InternalSensitivityEditField    matlab.ui.control.NumericEditField
        AccSensitivityVgEditFieldLabel  matlab.ui.control.Label
        AccSensitivityVgEditField       matlab.ui.control.NumericEditField
        AddChannelButton                matlab.ui.control.Button
        SaveConfigButton                matlab.ui.control.Button
        LoadConfigButton                matlab.ui.control.Button
        ChannelsListBoxLabel            matlab.ui.control.Label
        ChannelsListBox                 matlab.ui.control.ListBox
        RemoveSelectedChannelsButton    matlab.ui.control.Button
        LoadcellImpactHammerCheckBox    matlab.ui.control.CheckBox
        ScanForDevicesButton            matlab.ui.control.Button
        AcquisitionSettingsPanel        matlab.ui.container.Panel
        TriggerSettingsPanel            matlab.ui.container.Panel
        UseTriggerCheckBox              matlab.ui.control.CheckBox
        TriggersetpointVEditFieldLabel  matlab.ui.control.Label
        TriggersetpointVEditField       matlab.ui.control.NumericEditField
        TriggerWindowsSpinnerLabel      matlab.ui.control.Label
        TriggerWindowsSpinner           matlab.ui.control.Spinner
        TimeoutsEditFieldLabel          matlab.ui.control.Label
        TimeoutsEditField               matlab.ui.control.NumericEditField
        CollectionTimesEditFieldLabel   matlab.ui.control.Label
        CollectionTimesEditField        matlab.ui.control.NumericEditField
        SamplingFreqHzDropDownLabel     matlab.ui.control.Label
        SamplingFreqHzDropDown          matlab.ui.control.DropDown
        SystemmasskgEditFieldLabel      matlab.ui.control.Label
        SystemmasskgEditField           matlab.ui.control.NumericEditField
        ConfigurationDetailsPanel       matlab.ui.container.Panel
        TestarticleEditFieldLabel       matlab.ui.control.Label
        TestarticleEditField            matlab.ui.control.EditField
        TestEngineerEditFieldLabel      matlab.ui.control.Label
        TestEngineerEditField           matlab.ui.control.EditField
        EditField3Label                 matlab.ui.control.Label
        EditField3                      matlab.ui.control.EditField
        AcquireDataButton               matlab.ui.control.StateButton
        LoadDataButton                  matlab.ui.control.Button
        AddDatatoCummulativeButton      matlab.ui.control.Button
        CummulativeAcquisitionsTab      matlab.ui.container.Tab
        ChannelInfoPanel                matlab.ui.container.Panel
        ChannelListListBoxLabel         matlab.ui.control.Label
        ChannelListListBox              matlab.ui.control.ListBox
        ChannelDetailsTextAreaLabel     matlab.ui.control.Label
        ChannelDetailsTextArea          matlab.ui.control.TextArea
        ConfigurationInfoPanel          matlab.ui.container.Panel
        NotesTextAreaLabel              matlab.ui.control.Label
        NotesTextArea                   matlab.ui.control.TextArea
        ConfigurationdataTextAreaLabel  matlab.ui.control.Label
        ConfigurationdataTextArea       matlab.ui.control.TextArea
        DataInfoPanel                   matlab.ui.container.Panel
        SaveDataButton                  matlab.ui.control.Button
        NumberofSamplesEditFieldLabel   matlab.ui.control.Label
        NumberofSamplesEditField        matlab.ui.control.NumericEditField
        NumberofTestsEditFieldLabel     matlab.ui.control.Label
        NumberofTestsEditField          matlab.ui.control.NumericEditField
        NumberofChannelsEditFieldLabel  matlab.ui.control.Label
        NumberofChannelsEditField       matlab.ui.control.NumericEditField
        ClearcummulativedataButton      matlab.ui.control.Button
        SaveFigButton                   matlab.ui.control.Button
        PSDAxesCumm                     matlab.ui.control.UIAxes
        ImpactPSDAxesCumm               matlab.ui.control.UIAxes
        FrequencyResponseAxesCumm       matlab.ui.control.UIAxes
        CoheranceAxesCumm               matlab.ui.control.UIAxes
    end

    methods (Access = private)
        function populateDAQChannel(~, channel)
            global s;
            addAnalogInputChannel(s, channel.deviceName, channel.ID,  channel.measurementType)
            s.Channels(end).Name = channel.displayName;
            fields = fieldnames(channel.daqAttributes);
            for index = 1:length(fields)
                field = fields{index};
                if isfield(s.Channels(end), field)
                    currChannel = s.Channels(end);
                    currChannel.(field) = channel.daqAttributes.(field);
                    s.Channels(end) = currChannel;
                    
                end
            end
        end
        
        function s = configureDAQ(app)
            s = daq.createSession('ni');
            devices = daq.getDevices;
            if isempty(devices)
                uialert(app.UIFigure, 'Warning, no DAQ devices found. Make sure USB is plugged in. If problems persist, restart MATLAB.', 'No devices found!')
                s = [];
                return;
            end
            measurementTypes = devices(1,2).Subsystems.MeasurementTypesAvailable';
            app.MeasurementTypeDropDown.Items = measurementTypes;

            app.DevicetouseDropDown.Items = cellstr(char(devices.ID));
            
            app.ChannelIDDropDown.Items = devices(1,2).Subsystems.ChannelNames;
            
            app.ScanForDevicesButton.Enable = 'off';
        end
        
        function res = checkDAQConfig(app, s)
            if isempty(s)
                res = false;
                uialert(app.UIFigure, 'Warning, no DAQ devices found. Make sure USB is plugged in. If problems persist, restart MATLAB.', 'No devices found!');
                app.AcquireDataButton.Value = 0;
            else
                res = true;
            end
        end
        
        function res = checkAcquisitionConfig(app, s)
            if isempty(s.Channels)
                res = false;
                uialert(app.UIFigure, 'Warning, no DAQ Channels set up. Make sure to add channels to start Data Acquisition')
                app.AcquireDataButton.Value = 0;
                return
            elseif ~sum(getHammerChannels(app))
                res = false;
                uialert(app.UIFigure, 'Warning, no load cell Channel set up. Make sure to add at least one channel corresponding to the load cell of the hammer', 'No Load Cell Channel!');
                app.AcquireDataButton.Value = 0;
                return
            elseif app.CollectionTimesEditField.Value == 0
                res = false;
                uialert(app.UIFigure, 'Collection time must be positive real number', 'Invalid Collection Time');
                app.AcquireDataButton.Value = 0;
                return
            elseif app.SystemmasskgEditField.Value == 0
                res = false;
                uialert(app.UIFigure, 'System mass must be a positive real number', 'Invalid System Mass');
                app.AcquireDataButton.Value = 0;
                return
            else
                res = true;
            end
        end
        
        function collectData(app, src, event)
            global collectionComplete;
            global dataBuffer;
            global timeBuffer;
            global timeoutT0;
            global t0;
            global data;
            global time;
            
            disp('packet recieved')
            if t0 == 0
                disp('Waiting for trigger event...')
                if (toc(timeoutT0) > app.TimeoutsEditField.Value) && app.UseTriggerCheckBox.Value
                    collectionComplete = 2;
                    disp('TRIGGER TIME OUT')
                end
                if max(max(dataBuffer)) > app.TriggersetpointVEditField.Value
                    % Start to collect data
                    t0 = tic;
                    if max(timeBuffer) < min(event.TimeStamps)
                        data = [flipud(dataBuffer(timeBuffer > -1, :));event.Data];
                        time = [flipud(timeBuffer(timeBuffer > -1));event.TimeStamps];
                    else
                        data = [flipud(dataBuffer(timeBuffer > -1, :))];
                        time = [flipud(timeBuffer(timeBuffer > -1))];
                    end
                    
                    app.AcquireDataButton.Text = 'Acquiring Data...';
                else
                    shift = size(event.Data, 1);
                    if event.TimeStamps(1) > timeBuffer(1)
                        dataBuffer = circshift(dataBuffer, shift);
                        dataBuffer(1:shift, :) = flipud(event.Data);
                        
                        time_shift = size(event.TimeStamps, 1);
                        timeBuffer = circshift(timeBuffer, time_shift);
                        timeBuffer(1:time_shift, :) = flipud(event.TimeStamps);
                    end
                end
            elseif toc(t0) < app.CollectionTimesEditField.Value
                if isempty(time)
                    data = [data; event.Data];
                    time = [time; event.TimeStamps];
                    
                % This is a really sloppy fix to an issue where concurrent runs
                % of the data acquisition script would cause duplicate packets of
                % data to be imported. The root cause was never identified as of
                % 7/9/19
                elseif event.TimeStamps(1) > time(end)
                    data = [data; event.Data];
                    time = [time; event.TimeStamps];
                end
            else
                collectionComplete = 1;
                disp('FINISHED')
            end
        end
        
        function parsedData = parseData(app, hammerData, accelData, time, plotOn)

            Fs = str2double(app.SamplingFreqHzDropDown.Value);
            parsedData = struct();
            
            [parsedData.impactTs, parsedData.impactMags] = findImpact(app, hammerData, time, 1/mean(diff(time)), 0.0075);
            
            [parsedData.impactPSDMags, parsedData.impactPSDFs] = calcPSD(app, hammerData, 1/mean(diff(time)));
            
            [parsedData.freqRespMags, parsedData.freqRespFs] = calcFrequencyResponse(app, accelData, hammerData, 1/mean(diff(time)));
            
            [parsedData.PSDMags, parsedData.PSDFs] = calcPSD(app, accelData, Fs);
            
            [parsedData.coh, parsedData.cohFs] = calcCoherance(app, vecnorm(accelData(:, 1:3), 2, 2), vecnorm(accelData(:, 4:6), 2, 2), Fs);
            if plotOn
                plotData(app, parsedData, 0)
            end
        end
        
        function fig = plotData(app, parsedData, makeNewFig)
            global Channels
            fig = [];
            channelLegend = cellfun(@(channel) channel.name, Channels, 'UniformOutput', 0);
            accelLegend = channelLegend(cellfun(@(channel) ~channel.isLoadCell, Channels));    
            if makeNewFig
                fig = figure;
                tab1 = uitab('Title','Impact PSD');
                impactPSDAxes = axes(tab1);
                tab2 = uitab('Title','Frequency Response');
                frequencyResponseAxes = axes(tab2);
                tab3 = uitab('Title','PSD');
                PSDAxes = axes(tab3);
                tab4 = uitab('Title','Coherance');
                coheranceAxes = axes(tab4);
            elseif app.TabGroup.SelectedTab == app.TabGroup.Children(1)
                % Plot impact in time domain
                plot(app.ImpactWaveformAxes, parsedData.impactTs, parsedData.impactMags);
                
                impactPSDAxes = app.ImpactPSDAxes; 
                frequencyResponseAxes = app.FrequencyResponseAxes;
                PSDAxes = app.PSDAxes;
                coheranceAxes = app.CoheranceAxes;
            else
                impactPSDAxes = app.ImpactPSDAxesCumm; 
                frequencyResponseAxes = app.FrequencyResponseAxesCumm;
                PSDAxes = app.PSDAxesCumm;
                coheranceAxes = app.CoheranceAxesCumm;
            end
            
            % Plot PSD for the impact (over all time)
            loglog(impactPSDAxes, parsedData.impactPSDFs, parsedData.impactPSDMags);
            
            % Plot frequency response of the accelerometers to get 1/mass
            loglog(frequencyResponseAxes, parsedData.freqRespFs, parsedData.freqRespMags);
            mass = 1/app.SystemmasskgEditField.Value;
            hold(frequencyResponseAxes, 'on')
            loglog(frequencyResponseAxes, [parsedData.freqRespFs(1), parsedData.freqRespFs(end)], [1/mass, 1/mass], 'r--');
            hold(frequencyResponseAxes, 'off')
            frequencyResponseLegend = accelLegend;
            frequencyResponseLegend{end+1} = '1/mass';
            legend(frequencyResponseAxes, frequencyResponseLegend)
            
            % Plot welch estimate of PSD
            loglog(PSDAxes, parsedData.PSDFs, parsedData.PSDMags);
            legend(PSDAxes, accelLegend);
            
            % Plot magnitude squared coherance of the 2 accelerometers RMS
            % signals
            semilogx(coheranceAxes, parsedData.cohFs, parsedData.coh);
        end
        
        function [PSD,freqs] = calcPSD(app, data, Fs)
            % return the PSD of a dataset
            n = length(data)/2;
            [PSD, freqs] = pwelch(data, n, [], n, Fs);
        end
        
        function [ts, ds] = findImpact(app, data, time, Fs, windowTime)
            % Find the impact profile and return the corresponding data
            [~, impactIndex] = findpeaks(data, "MinPeakHeight", std(data) * 10, "MinPeakDistance", round(0.01*Fs));
            windowWidth = round(windowTime * Fs / 2);

            if length(impactIndex) > 1
                uialert(app.UIFigure, 'Multiple Peaks Detected! Consider rejecting data', 'Multiple Peaks found')
            elseif isempty(impactIndex)
                uialert(app.UIFigure, 'Unable to find a peak, Consider rejecting data', 'No peak found') 
                ts = time;
                ds = data;
                return
            end
            if impactIndex(1) < windowWidth
                frame = 1:1:(impactIndex(end) + windowWidth);
            elseif impactIndex(end) + windowWidth > length(time)
                frame = impactIndex(1):length(time);
            else
                frame = (impactIndex(1) - windowWidth):1:(impactIndex(end) + windowWidth);
            end
            ts = time(frame);
            ds = data(frame);
        end
        
        function [coh, freqs] = calcCoherance(app, data1, data2, Fs)
        % Windowing? What is the best window configuration to use here?    
            windowSize = round(0.25 * str2double(app.SamplingFreqHzDropDown.Value));
            [coh,freqs] = mscohere(data1, data2, hamming(windowSize), [], [], Fs);

        end
        
        function [ys, fs] = calcFrequencyResponse(app, accelData, hammerData, Fs)
            % TODO: Check semantics here... mass divided by acceleration?
            % force? what?
            
            [accelPSD, fs] = calcPSD(app, accelData, Fs);
            [hammerPSD, ~] = calcPSD(app, hammerData, Fs);
            
            ys = accelPSD ./ hammerPSD;
            
        end
        
        function [freqs, ys] = calcFFT(app, data, time, Fs)
            L = length(time);
            ys = fftshift((abs(fft(data))));
            freqs = (-L/2:1:L/2-1) * Fs / L;
        end
        
        function [SD, freqs] = calcSpectralDensity(app, data1, data2, Fs)
            L = length(data1);
            SD = fftshift((conj(fft(data1)) .* fft(data2)) / (Fs / 2 * L))';
            freqs = [-L/2:1:L/2-1] * Fs / L;
            SD = SD(:, freqs > 0);
            freqs = freqs(freqs > 0);
        end
        function inds = getHammerChannels(app)
            global Channels;
            inds = zeros([1,length(Channels)]);
            for i = 1:length(Channels)
                inds(i) = Channels{i}.isLoadCell;
            end
            inds = logical(inds);
        end
        
        function togglePannel(app, panel, status)
            elements = panel.Children;
            for i = 1:length(elements)
                element = elements(i);
                if isa(element, 'matlab.ui.container.Panel')
                    togglePannel(app, element, status)
                else 
                    if isprop(element, 'Enable')
                       element.Enable = status;
                    end
                end
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global s;
            global Channels;
            global testDateTime;
            global cummTime;
            global cummData;
            
            cummTime = {};
            cummData = {};
            testDateTime = NaN;
            Channels = {};
            % Start DAQ session
            s = configureDAQ(app);
            freqs = linspace(51200, 51200/16, 16);
            app.SamplingFreqHzDropDown.Items = cellstr(strsplit(num2str(freqs)));
        end

        % Button pushed function: AddChannelButton
        function AddChannelButtonPushed(app, event)
            global Channels;
            global s;
            if ~checkDAQConfig(app, s)
                return
            end
            channel = struct();
            daqAttributes = struct();
            channel.name = app.ChannelnameEditField.Value;
            
            deviceName = app.DevicetouseDropDown.Value;
            
            assert(~strcmp(deviceName, 'Device to use'), 'No valid Device found.')
            channel.deviceName = deviceName;
            
            assert(~strcmp(app.ChannelIDDropDown.Value, 'Channel ID'), 'Must select Channel!')
            channel.ID = app.ChannelIDDropDown.Value;
            
            channel.measurementType = app.MeasurementTypeDropDown.Value;
            
            channel.isLoadCell = app.LoadcellImpactHammerCheckBox.Value;
            
            daqAttributes.Sensitivity = app.AccSensitivityVgEditField.Value;
            
            if strcmp(channel.measurementType, 'IEPE')
                app.ACcouplingCheckBox.Value = 1;
                daqAttributes.ExcitationCurrent = str2double(app.ExcitationcurrentAEditField.Value);
            elseif strcmp(channel.measurementType, 'Accelerometer')
                app.ACcouplingCheckBox.Value = 0;
                daqAttributes.ExcitationSource = 'None';
            end
            
            if app.ACcouplingCheckBox.Value
                daqAttributes.Coupling = 'AC';
            else
                daqAttributes.Coupling = 'DC';
            end 
            channel.daqAttributes = daqAttributes;
            displayName = strcat('Ch: ', channel.name, {' '}, channel.deviceName, {' '}, channel.ID, {' | '}, channel.daqAttributes.Coupling);
            if channel.isLoadCell
                displayName = append(displayName, ' | Load Cell');
            end
            channel.displayName = displayName{1};
            
            channelConfig = strcat(channel.deviceName, channel.ID);
            
            allChannelNames = cellfun(@(channel) strcat(channel.deviceName, channel.ID), Channels, 'UniformOutput', false);
            if isempty(allChannelNames)
                app.ChannelsListBox.Items = {};
            end
            
            isUnique = sum(cellfun(@(name) strcmp(name, channelConfig), allChannelNames)) == 0;
            assert(isUnique, 'Duplicate channel name/configuration')
            populateDAQChannel(app, channel);
            Channels{end+1} = channel;
            app.ChannelsListBox.Items{end + 1} = channel.displayName;
        end

        % Button pushed function: RemoveSelectedChannelsButton
        function RemoveSelectedChannelsButtonPushed(app, event)
            global Channels;
            global s;
            if ~checkDAQConfig(app, s)
                return
            end
            selectedChannelNames = app.ChannelsListBox.Value;
            channelNames = cellfun(@(channel) channel.displayName, Channels, 'UniformOutput', 0);
            daqChannelNames = arrayfun(@(channel) channel.Name, s.Channels, 'UniformOutput', 0);
            DAQChannelsToRemove = zeros(size(s.Channels));
            remainingNames = ones(size(Channels));
            
            for i = 1:length(selectedChannelNames)
                targetName = selectedChannelNames{i};
                remainingNames = remainingNames - strcmp(channelNames, targetName);
                DAQChannelsToRemove = DAQChannelsToRemove + strcmp(daqChannelNames, targetName);
            end
            
            DAQChannelsToRemove = nonzeros(DAQChannelsToRemove .* (1:length(s.Channels)));
            removeChannel(s, DAQChannelsToRemove)
            app.ChannelsListBox.Value = {};
            if numel(nonzeros(remainingNames)) > 0
                Channels = Channels(logical(remainingNames));
                app.ChannelsListBox.Items = channelNames(logical(remainingNames));
            else
                Channels = {};
                app.ChannelsListBox.Items = {};
            end
        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)
            global Channels;
            global s;
            if ~checkDAQConfig(app, s)
                return
            end
            save('hammer_config.mat', 'Channels');
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            global Channels;
            global s;
            uiopen('load');
            s = daq.createSession('ni');
            app.ChannelsListBox.Items = {};
            app.ChannelsListBox.Value = {};
            for i = 1:length(Channels)
               populateDAQChannel(app, Channels{i});
            end
            app.ChannelsListBox.Items = cellfun(@(channel) channel.displayName, Channels, 'UniformOutput', 0);
        end

        % Value changed function: UseTriggerCheckBox
        function UseTriggerCheckBoxValueChanged(app, event)
            value = app.UseTriggerCheckBox.Value;
            if value
                app.TriggersetpointVEditField.Enable = 'on';
                app.TriggerWindowsSpinner.Enable = 'on';
                app.TimeoutsEditField.Enable = 'on';

            else
                app.TriggersetpointVEditField.Enable = 'off';
                app.TriggerWindowsSpinner.Enable = 'off';
                app.TimeoutsEditField.Enable = 'off';
            end
        end

        % Value changed function: AcquireDataButton
        function AcquireDataButtonValueChanged(app, event)
            global s;
            if ~checkDAQConfig(app, s) || ~checkAcquisitionConfig(app, s)
                return;
            end
            
            app.AcquireDataButton.Enable = 'off';
            cla(app.ImpactWaveformAxes);
            cla(app.ImpactPSDAxes);
            global t0;            
            global collectionComplete;
            global time;
            global data;
            global timeBuffer;
            global dataBuffer;
            global timeoutT0;
            global testDateTime;
            global Channels;
            
            collectionComplete = 0;
            time = [];
            data = [];
            s.Rate = str2double(app.SamplingFreqHzDropDown.Value);
            s.IsContinuous = 1;
            lh = addlistener(s, 'DataAvailable', @app.collectData);
            
            triggerWindow = app.TriggerWindowsSpinner.Value;
            dataBuffer = zeros([s.Rate * triggerWindow, length(s.Channels)]);
            timeBuffer = ones([s.Rate * triggerWindow, 1]) * -1;
            
            if ~isdatetime(testDateTime)
                testDateTime = datetime;
            end
            
            if app.UseTriggerCheckBox.Value
                app.AcquireDataButton.Text = 'Waiting for Trigger...';
                t0 = 0;
            else
                app.AcquireDataButton.Text = 'Acquiring Data...';
                t0 = tic;
            end
            
            timeoutT0 = tic;
            startBackground(s);
            cla(app.ImpactWaveformAxes);
            cla(app.ImpactPSDAxes);
            cla(app.FrequencyResponseAxes);
            cla(app.PSDAxes);
            cla(app.CoheranceAxes);
            
            while ~collectionComplete
                pause(1)
                disp(s.IsRunning)
            end
            stop(s);
            app.AcquireDataButton.Text = 'Acquire Data';
            app.AcquireDataButton.Value = 0;
            app.AcquireDataButton.Enable = 'on';
            
            data(:, ~getHammerChannels(app)) = data(:, ~getHammerChannels(app)) .* cellfun(@(channel) channel.daqAttributes.Sensitivity, Channels(~getHammerChannels(app)));
            
            t = time;
            d = data;
            if ~isempty(t)
                parseData(app, d(:, getHammerChannels(app)), d(:, ~getHammerChannels(app)), t, 1);
            end

        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)

%             [file, path] = uigetfile('*.mat');
%             global time;
%             global data;
%             
%             if isequal(file,0)
%                 return;
%             else
%                 load(fullfile(path, file), "-mat", 'data', 'time', 'Channels', 'ChannelProps');
%             end
            
            global time;
            global data;
            path = 'C:\Users\Jonathan Zerez\Documents\GitHub\TMT_Vibration_toolkit\';
            file = '2019-02-07 0938 large hammer impact 15s on mass PCB WX low and load cell servo off on oil AccData.mat';
            load(fullfile(path, file), "-mat", 'data', 'time', 'Channels', 'ChannelProps')
            if exist('ChannelProps', 'var')
                [~, hammerChannel] = min(abs(mad(data, 1) ./ median(data)));
                hammerInds = zeros([1,length(ChannelProps)]);
                hammerInds(hammerChannel) = 1;
                hammerInds = logical(hammerInds);
            end
            
            parseData(app, data(:, hammerInds), data(:, ~hammerInds), time, 1);
            
            time = []; data = [];
        end

        % Value changed function: ChannelListListBox
        function ChannelListListBoxValueChanged(app, event)
            global Channels;
            value = app.ChannelListListBox.Value;
            selectedChannelIndex = strcmp(cellfun(@(channel) channel.displayName, Channels, 'UniformOutput', false), value);
            channel = Channels{selectedChannelIndex};
            fields = fieldnames(channel);
            values = struct2cell(channel);
            string = '';
            for line = 1:length(fields)
                if ischar(values{line}) || isstring(values{line})
                    string = append(string, fields{line}, ': ', values{line}, newline);
                elseif islogical(values{line})
                    string = append(string, fields{line}, ': ', num2str(values{line}), newline);
                else
                    string = append(string, fields{line}, ': ', newline);
                    subfields = fieldnames(values{line});                    
                    subvalues = struct2cell(values{line});
                    for subline = 1:length(subfields)
                        string = append(string, char(9), subfields{subline}, ': ', num2str(subvalues{subline}), newline);
                    end
                end
            end
            app.ChannelDetailsTextArea.Value = string;
        end

        % Button pushed function: AddDatatoCummulativeButton
        function AddDatatoCummulativeButtonPushed(app, event)
            
            global time;
            global data;
            global Channels;
            global cummTime;
            global cummData;
            if ~isempty(time)
                if isempty(cummTime)
                    response = uiconfirm(app.UIFigure, 'Warning, proceeding with this action will lock all Acquisition Settings. The only way to reset them is to clear the cummulative data. Please make sure that all settings are what you want them to be', 'Locking Acquisition Settings');
                    if ~strcmp(response, 'OK')
                        return
                    end
                end
                app.NumberofTestsEditField.Value = app.NumberofTestsEditField.Value + 1;
                app.NumberofSamplesEditField.Value = app.NumberofSamplesEditField.Value + length(time);
                if app.NumberofChannelsEditField.Value == 0
                    app.NumberofChannelsEditField.Value = length(Channels);
                end
                cummTabName = app.TabGroup.Children(2).Title;
                if strcmp(cummTabName, 'Cummulative Acquisitions')
                    app.TabGroup.Children(2).Title = append(cummTabName, ' (', num2str(app.NumberofTestsEditField.Value), ')');    
                else
                    app.TabGroup.Children(2).Title = append(cummTabName(1:end-4), ' (', num2str(app.NumberofTestsEditField.Value), ')'); 
                end
                
                cummTime{end+1} = time;
                cummData{end+1} = data;
                time = [];
                data = [];
                
                togglePannel(app, app.AcquisitionSettingsPanel, 'off')
                
            else
                uialert(app.UIFigure, 'No data to add! Collect Data first.', 'No data')
            end
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            global cummTime;
            global cummData;
            global testDateTime;
            selectedTab = app.TabGroup.SelectedTab;
            if strcmp(selectedTab.Title, app.TabGroup.Children(2).Title)
                app.ChannelListListBox.Items = app.ChannelsListBox.Items;
                string = append(app.TestarticleEditFieldLabel.Text, ': ', app.TestarticleEditField.Value, newline);
                string = append(string, app.TestEngineerEditFieldLabel.Text, ': ', app.TestEngineerEditField.Value, newline);
                
                if isdatetime(testDateTime)
                    string = append(string, 'Date and Time: ', datestr(testDateTime));
                end
                app.ConfigurationdataTextArea.Value = string;
                
                if ~isempty(cummTime)
                    averagedData = parseData(app, cummData{1}(:, getHammerChannels(app)), cummData{1}(:, ~getHammerChannels(app)), cummTime{1}, 0);
                    dataFields = fieldnames(averagedData);
                    for i = 2:length(cummTime)
                        res = parseData(app, cummData{i}(:, getHammerChannels(app)), cummData{i}(:, ~getHammerChannels(app)), cummTime{i}, 0);
                        for j = 1:length(dataFields)
                            field = dataFields{j};
                            averagedData.(field) = averagedData.(field) + res.(field);
                        end
                    end
                    for i = 1:length(dataFields)
                        field = dataFields{i};
                        if ~contains(field, 'Fs')
                            averagedData.(field) = averagedData.(field) / length(cummTime);
                        end
                    end
                    plotData(app, averagedData, 0)
                end
            end
        end

        % Button pushed function: ClearcummulativedataButton
        function ClearcummulativedataButtonPushed(app, event)
            global cummTime;
            global cummData;
            global testDateTime;
            
            response = uiconfirm(app.UIFigure, 'Are you sure you want to clear all cummulative data?', 'Clear Data', 'Icon', 'warning');
            if strcmp(response, 'OK')
                app.NumberofTestsEditField.Value = 0;
                app.NumberofSamplesEditField.Value = 0;
                app.NumberofChannelsEditField.Value = 0;
                app.TabGroup.Children(2).Title = 'Cummulative Acquisitions';
                
                cummTime = {};
                cummData = {};
                testDateTime = NaN;
                cla(app.FrequencyResponseAxesCumm)
                cla(app.PSDAxesCumm)
                cla(app.CoheranceAxesCumm)
                app.togglePanel(app, app.AcquisitionSettingsPanel, 'on')
                if ~app.UseTriggerCheckBox.Value
                    app.TriggerWindowsSpinner.Enable = 'off';
                    app.TriggersetpointVEditField.Enable = 'off';
                    app.TimeoutsEditField.Enable = 'off';
                end
            end
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            global cummTime;
            global cummData;
            
        end

        % Button pushed function: ScanForDevicesButton
        function ScanForDevicesButtonPushed(app, event)
            global s;
            s = configureDAQ(app);
        end

        % Button pushed function: SaveFigButton
        function SaveFigButtonPushed(app, event)
            global cummTime;
            global cummData;
            
            if ~isempty(cummTime)
                averagedData = parseData(app, cummData{1}(:, getHammerChannels(app)), cummData{1}(:, ~getHammerChannels(app)), cummTime{1}, 0);
                dataFields = fieldnames(averagedData);
                for i = 2:length(cummTime)
                    res = parseData(app, cummData{i}(:, getHammerChannels(app)), cummData{i}(:, ~getHammerChannels(app)), cummTime{i}, 0);
                    for j = 1:length(dataFields)
                        field = dataFields{j};
                        averagedData.(field) = averagedData.(field) + res.(field);
                    end
                end
                for i = 1:length(dataFields)
                    field = dataFields{i};
                    if ~contains(field, 'Fs')
                        averagedData.(field) = averagedData.(field) / length(cummTime);
                    end
                end
                f = plotData(app, averagedData, 1);
                [FileName, PathName] = uiputfile('*.fig','Save Entire Figure As...');
                hgsave(fullfile(PathName,FileName));
                close(f);
            else
                uialert(app.UIFigure, 'No data to add! Collect Data first.', 'No data')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LatestacquisitionTab = uitab(app.TabGroup);
            app.LatestacquisitionTab.Title = 'Latest acquisition';
            app.ImpactWaveformAxes = uiaxes(app.LatestacquisitionTab);
            title(app.ImpactWaveformAxes, 'Impact Waveform')
            xlabel(app.ImpactWaveformAxes, 't (s)')
            ylabel(app.ImpactWaveformAxes, 'mag (V)')
            app.ImpactPSDAxes = uiaxes(app.LatestacquisitionTab);
            title(app.ImpactPSDAxes, 'Impact PSD')
            xlabel(app.ImpactPSDAxes, 'f (Hz)')
            ylabel(app.ImpactPSDAxes, 'mag')
            app.PSDAxes = uiaxes(app.LatestacquisitionTab);
            title(app.PSDAxes, 'PSD')
            xlabel(app.PSDAxes, 'f (Hz)')
            ylabel(app.PSDAxes, 'g^2 / Hz')
            app.CoheranceAxes = uiaxes(app.LatestacquisitionTab);
            title(app.CoheranceAxes, 'Coherance')
            xlabel(app.CoheranceAxes, 'f (Hz)')
            ylabel(app.CoheranceAxes, 'Cohereance')
            app.FrequencyResponseAxes = uiaxes(app.LatestacquisitionTab);
            title(app.FrequencyResponseAxes, 'Frequency Response (1/m)')
            xlabel(app.FrequencyResponseAxes, 'f (Hz)')
            ylabel(app.FrequencyResponseAxes, '1/m (1/kg)')
            app.ChannelSettingsPanel = uipanel(app.LatestacquisitionTab);
            app.ChannelSettingsPanel.Title = 'Channel Settings';
            app.ChannelnameEditFieldLabel = uilabel(app.ChannelSettingsPanel);
            app.ChannelnameEditFieldLabel.Text = 'Channel name';
            app.ChannelnameEditField = uieditfield(app.ChannelSettingsPanel, 'text');
            app.DevicetouseDropDown = uidropdown(app.ChannelSettingsPanel);
            app.DevicetouseDropDown.Items = {'Device to use', ' bobby', ''};
            app.DevicetouseDropDown.Value = 'Device to use';
            app.MeasurementTypeDropDown = uidropdown(app.ChannelSettingsPanel);
            app.MeasurementTypeDropDown.Items = {'Voltage'};
            app.MeasurementTypeDropDown.Value = 'Voltage';
            app.ACcouplingCheckBox = uicheckbox(app.ChannelSettingsPanel);
            app.ACcouplingCheckBox.Text = 'AC coupling';
            app.ChannelIDDropDown = uidropdown(app.ChannelSettingsPanel);
            app.ChannelIDDropDown.Items = {'Channel ID'};
            app.ChannelIDDropDown.Value = 'Channel ID';
            app.ExcitationcurrentAEditFieldLabel = uilabel(app.ChannelSettingsPanel);
            app.ExcitationcurrentAEditFieldLabel.Text = 'Excitation current (A)';
            app.ExcitationcurrentAEditField = uieditfield(app.ChannelSettingsPanel, 'numeric');
            app.ExcitationcurrentAEditField.Limits = [0.002 0.01];
            app.ExcitationcurrentAEditField.Value = 0.002;
            app.InternalSensitivityEditFieldLabel = uilabel(app.ChannelSettingsPanel);
            app.InternalSensitivityEditFieldLabel.Text = 'Internal Sensitivity';
            app.InternalSensitivityEditField = uieditfield(app.ChannelSettingsPanel, 'numeric');
            app.InternalSensitivityEditField.Limits = [0 Inf];
            app.AccSensitivityVgEditFieldLabel = uilabel(app.ChannelSettingsPanel);
            app.AccSensitivityVgEditFieldLabel.Text = 'Acc. Sensitivity (V/g)';
            app.AccSensitivityVgEditField = uieditfield(app.ChannelSettingsPanel, 'numeric');
            app.AccSensitivityVgEditField.Limits = [0 1000];
            app.AddChannelButton = uibutton(app.ChannelSettingsPanel, 'push');
            app.AddChannelButton.ButtonPushedFcn = createCallbackFcn(app, @AddChannelButtonPushed, true);
            app.AddChannelButton.Text = 'Add Channel';
            app.SaveConfigButton = uibutton(app.ChannelSettingsPanel, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Text = 'Save Config';
            app.LoadConfigButton = uibutton(app.ChannelSettingsPanel, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load Config';
            app.ChannelsListBoxLabel = uilabel(app.ChannelSettingsPanel);
            app.ChannelsListBoxLabel.Text = 'Channels';
            app.ChannelsListBox = uilistbox(app.ChannelSettingsPanel);
            app.ChannelsListBox.Items = {'No channels'};
            app.ChannelsListBox.Multiselect = 'on';
            app.ChannelsListBox.Value = {'No channels'};
            app.RemoveSelectedChannelsButton = uibutton(app.ChannelSettingsPanel, 'push');
            app.RemoveSelectedChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSelectedChannelsButtonPushed, true);
            app.RemoveSelectedChannelsButton.Text = 'Remove Selected Channels';
            app.LoadcellImpactHammerCheckBox = uicheckbox(app.ChannelSettingsPanel);
            app.LoadcellImpactHammerCheckBox.Text = 'Load cell (Impact Hammer)';
            app.ScanForDevicesButton = uibutton(app.ChannelSettingsPanel, 'push');
            app.ScanForDevicesButton.ButtonPushedFcn = createCallbackFcn(app, @ScanForDevicesButtonPushed, true);
            app.ScanForDevicesButton.Text = 'Scan For Devices';
            app.AcquisitionSettingsPanel = uipanel(app.LatestacquisitionTab);
            app.AcquisitionSettingsPanel.Title = 'Acquisition Settings';
            app.TriggerSettingsPanel = uipanel(app.AcquisitionSettingsPanel);
            app.TriggerSettingsPanel.Title = 'Trigger Settings';
            app.UseTriggerCheckBox = uicheckbox(app.TriggerSettingsPanel);
            app.UseTriggerCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseTriggerCheckBoxValueChanged, true);
            app.UseTriggerCheckBox.Text = 'Use Trigger';
            app.TriggersetpointVEditFieldLabel = uilabel(app.TriggerSettingsPanel);
            app.TriggersetpointVEditFieldLabel.Text = 'Trigger setpoint (V)';
            app.TriggersetpointVEditField = uieditfield(app.TriggerSettingsPanel, 'numeric');
            app.TriggersetpointVEditField.Limits = [0 1];
            app.TriggersetpointVEditField.Enable = 'off';
            app.TriggersetpointVEditField.Value = 0.007;
            app.TriggerWindowsSpinnerLabel = uilabel(app.TriggerSettingsPanel);
            app.TriggerWindowsSpinnerLabel.Text = 'Trigger Window (s)';
            app.TriggerWindowsSpinner = uispinner(app.TriggerSettingsPanel);
            app.TriggerWindowsSpinner.Step = 0.25;
            app.TriggerWindowsSpinner.Limits = [0 5];
            app.TriggerWindowsSpinner.Enable = 'off';
            app.TriggerWindowsSpinner.Value = 0.25;
            app.TimeoutsEditFieldLabel = uilabel(app.TriggerSettingsPanel);
            app.TimeoutsEditFieldLabel.Text = 'Timeout (s)';
            app.TimeoutsEditField = uieditfield(app.TriggerSettingsPanel, 'numeric');
            app.TimeoutsEditField.Limits = [1 120];
            app.TimeoutsEditField.Enable = 'off';
            app.TimeoutsEditField.Value = 15;
            app.CollectionTimesEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.CollectionTimesEditFieldLabel.Text = 'Collection Time (s)';
            app.CollectionTimesEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.CollectionTimesEditField.Limits = [0 100];
            app.CollectionTimesEditField.Value = 5;
            app.SamplingFreqHzDropDownLabel = uilabel(app.AcquisitionSettingsPanel);
            app.SamplingFreqHzDropDownLabel.Text = 'Sampling Freq (Hz)';
            app.SamplingFreqHzDropDown = uidropdown(app.AcquisitionSettingsPanel);
            app.SamplingFreqHzDropDown.Items = {'51200'};
            app.SamplingFreqHzDropDown.Value = '51200';
            app.SystemmasskgEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.SystemmasskgEditFieldLabel.Text = 'System mass (kg)';
            app.SystemmasskgEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.SystemmasskgEditField.Limits = [0 100];
            app.SystemmasskgEditField.Value = 1;
            app.ConfigurationDetailsPanel = uipanel(app.LatestacquisitionTab);
            app.ConfigurationDetailsPanel.Title = 'Configuration Details';
            app.TestarticleEditFieldLabel = uilabel(app.ConfigurationDetailsPanel);
            app.TestarticleEditFieldLabel.Text = 'Test article';
            app.TestarticleEditField = uieditfield(app.ConfigurationDetailsPanel, 'text');
            app.TestEngineerEditFieldLabel = uilabel(app.ConfigurationDetailsPanel);
            app.TestEngineerEditFieldLabel.Text = 'Test Engineer';
            app.TestEngineerEditField = uieditfield(app.ConfigurationDetailsPanel, 'text');
            app.EditField3Label = uilabel(app.ConfigurationDetailsPanel);
            app.EditField3Label.Text = 'Edit Field3';
            app.EditField3 = uieditfield(app.ConfigurationDetailsPanel, 'text');
            app.AcquireDataButton = uibutton(app.LatestacquisitionTab, 'state');
            app.AcquireDataButton.ValueChangedFcn = createCallbackFcn(app, @AcquireDataButtonValueChanged, true);
            app.AcquireDataButton.Text = 'Acquire Data';
            app.LoadDataButton = uibutton(app.LatestacquisitionTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.AddDatatoCummulativeButton = uibutton(app.LatestacquisitionTab, 'push');
            app.AddDatatoCummulativeButton.ButtonPushedFcn = createCallbackFcn(app, @AddDatatoCummulativeButtonPushed, true);
            app.AddDatatoCummulativeButton.Text = 'Add Data to Cummulative';
            app.CummulativeAcquisitionsTab = uitab(app.TabGroup);
            app.CummulativeAcquisitionsTab.Title = 'Cummulative Acquisitions';
            app.ChannelInfoPanel = uipanel(app.CummulativeAcquisitionsTab);
            app.ChannelInfoPanel.Title = 'Channel Info';
            app.ChannelListListBoxLabel = uilabel(app.ChannelInfoPanel);
            app.ChannelListListBoxLabel.Text = 'Channel List';
            app.ChannelListListBox = uilistbox(app.ChannelInfoPanel);
            app.ChannelListListBox.Items = {'No channels'};
            app.ChannelListListBox.ValueChangedFcn = createCallbackFcn(app, @ChannelListListBoxValueChanged, true);
            app.ChannelListListBox.Value = 'No channels';
            app.ChannelDetailsTextAreaLabel = uilabel(app.ChannelInfoPanel);
            app.ChannelDetailsTextAreaLabel.Text = 'Channel Details';
            app.ChannelDetailsTextArea = uitextarea(app.ChannelInfoPanel);
            app.ChannelDetailsTextArea.Editable = 'off';
            app.ConfigurationInfoPanel = uipanel(app.CummulativeAcquisitionsTab);
            app.ConfigurationInfoPanel.Title = 'Configuration Info';
            app.NotesTextAreaLabel = uilabel(app.ConfigurationInfoPanel);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.ConfigurationInfoPanel);
            app.NotesTextArea.Editable = 'off';
            app.ConfigurationdataTextAreaLabel = uilabel(app.ConfigurationInfoPanel);
            app.ConfigurationdataTextAreaLabel.Text = 'Configuration data';
            app.ConfigurationdataTextArea = uitextarea(app.ConfigurationInfoPanel);
            app.ConfigurationdataTextArea.Editable = 'off';
            app.DataInfoPanel = uipanel(app.CummulativeAcquisitionsTab);
            app.DataInfoPanel.Title = 'Data Info';
            app.SaveDataButton = uibutton(app.DataInfoPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.NumberofSamplesEditFieldLabel = uilabel(app.DataInfoPanel);
            app.NumberofSamplesEditFieldLabel.Text = 'Number of Samples';
            app.NumberofSamplesEditField = uieditfield(app.DataInfoPanel, 'numeric');
            app.NumberofSamplesEditField.Limits = [0 Inf];
            app.NumberofSamplesEditField.ValueDisplayFormat = '%11.3g';
            app.NumberofSamplesEditField.Editable = 'off';
            app.NumberofTestsEditFieldLabel = uilabel(app.DataInfoPanel);
            app.NumberofTestsEditFieldLabel.Text = 'Number of Tests';
            app.NumberofTestsEditField = uieditfield(app.DataInfoPanel, 'numeric');
            app.NumberofTestsEditField.Limits = [0 Inf];
            app.NumberofTestsEditField.Editable = 'off';
            app.NumberofChannelsEditFieldLabel = uilabel(app.DataInfoPanel);
            app.NumberofChannelsEditFieldLabel.Text = 'Number of Channels';
            app.NumberofChannelsEditField = uieditfield(app.DataInfoPanel, 'numeric');
            app.NumberofChannelsEditField.Limits = [0 Inf];
            app.NumberofChannelsEditField.Editable = 'off';
            app.ClearcummulativedataButton = uibutton(app.DataInfoPanel, 'push');
            app.ClearcummulativedataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearcummulativedataButtonPushed, true);
            app.ClearcummulativedataButton.Text = 'Clear cummulative data';
            app.SaveFigButton = uibutton(app.DataInfoPanel, 'push');
            app.SaveFigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigButtonPushed, true);
            app.SaveFigButton.Text = 'Save Fig';
            app.PSDAxesCumm = uiaxes(app.CummulativeAcquisitionsTab);
            title(app.PSDAxesCumm, 'Cummulative PSD')
            xlabel(app.PSDAxesCumm, 'X')
            ylabel(app.PSDAxesCumm, 'Y')
            app.ImpactPSDAxesCumm = uiaxes(app.CummulativeAcquisitionsTab);
            title(app.ImpactPSDAxesCumm, 'Cummulative Impact PSD')
            xlabel(app.ImpactPSDAxesCumm, 'X')
            ylabel(app.ImpactPSDAxesCumm, 'Y')
            app.FrequencyResponseAxesCumm = uiaxes(app.CummulativeAcquisitionsTab);
            title(app.FrequencyResponseAxesCumm, 'Cumulative Frequency Response')
            xlabel(app.FrequencyResponseAxesCumm, 'X')
            ylabel(app.FrequencyResponseAxesCumm, 'Y')
            app.CoheranceAxesCumm = uiaxes(app.CummulativeAcquisitionsTab);
            title(app.CoheranceAxesCumm, 'Cummulative Coherance')
            xlabel(app.CoheranceAxesCumm, 'X')
            ylabel(app.CoheranceAxesCumm, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hammer_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end